classdef smoothing_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        AutomaticknotplacementButton  matlab.ui.control.Button
        StateDropDown                 matlab.ui.control.DropDown
        StateDropDownLabel            matlab.ui.control.Label
        CellsSlider                   matlab.ui.control.Slider
        CellsLabel                    matlab.ui.control.Label
        FinishButton                  matlab.ui.control.Button
        correctionmultiplierSlider    matlab.ui.control.Slider
        logLabel                      matlab.ui.control.Label
        EquidistantknotsSlider        matlab.ui.control.Slider
        EquidistantknotsSliderLabel   matlab.ui.control.Label
        LiveCheckBox                  matlab.ui.control.CheckBox
        SmoothButton                  matlab.ui.control.Button
        KnotsUIAxes_2                 matlab.ui.control.UIAxes
        KnotsUIAxes                   matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        smoother
        tl
        lastplots
        active
        newlambda
        
        state_lambda
        
        knots
    end
    
    methods (Access = private)
        function smooth(app)
            rng('default')
            
            app.smoother.settings.order = app.OrderSlider.Value;
            app.smoother.settings.knots = [0 app.knots 1];
            app.smoother.settings.linear = app.smoother.data.t(end) * [app.LinearSlider.Value 1];
            if app.newlambda
                if app.correctionmultiplierSlider.Enable
                    app.smoother.lambda = 10^app.correctionmultiplierSlider.Value * ones(1, length(app.smoother.lambda));
                else
                    app.smoother.lambda = app.state_lambda;
                end
            else
                app.smoother.settings.interactive = false;
            end
            
            app.smoother.bspline = BSpline(app.smoother.settings.order, app.smoother.settings.knots);
            app.smoother.optimize()
            
            if ~app.newlambda, app.smoother.settings.interactive = true; end
        end
        
        function plot_smoothed(app)
            for idx = 1:numel(app.lastplots), delete(app.lastplots(idx)), end
            
            n_cells = app.CellsSlider.Value;
            observed_indices = find(app.active);
            for idx = 1:length(observed_indices)
                ax = nexttile(app.tl, idx);
                app.lastplots(1:n_cells, idx) = plot(ax, app.smoother.fine * app.smoother.data.t(end) + app.smoother.data.t(1), ...
                                                     reshape(app.smoother.data.smoothed_fine(:, observed_indices(idx), 1:n_cells), ...
                                                             length(app.smoother.fine), []), ...
                                                     '--', 'LineWidth', 1.25);
                set(app.lastplots(1:n_cells, idx), {'color'}, num2cell(turbo(n_cells), 2));
                title(ax, app.smoother.system.states(app.smoother.data.observed(observed_indices(idx))))
                xlabel(ax, 'time (min)')
                ylabel(ax, 'value (AU)')
            end
        end
        
        function setup_plots(app)
            nplots = sum(app.active);
            ncols = ceil(sqrt(nplots));
            nrows = ceil(nplots / ncols);
            delete(app.tl)
            app.tl = tiledlayout(app.RightPanel, nrows, ncols);
            
            n_cells = app.CellsSlider.Value;
            observed_indices = find(app.active);
            for idx = 1:length(observed_indices)
                ax = nexttile(app.tl, idx);
                hold(ax, 'on')
                h = plot(ax, app.smoother.data.t, ...
                         reshape(app.smoother.data.traces(:, observed_indices(idx), 1:n_cells), ...
                                 length(app.smoother.data.t), []));
                set(h, {'color'}, num2cell(turbo(n_cells), 2));
                title(ax, app.smoother.system.states(app.smoother.data.observed(observed_indices(idx))))
                xlabel(ax, 'time (min)')
                ylabel(ax, 'value (AU)')
            end
        end
        
        function update_knots(app)
            app.smooth();
            
            t = app.smoother.fine * range(app.smoother.data.t) + app.smoother.data.t(1);
            basis = app.smoother.X_fine;
            
            plot(app.KnotsUIAxes, t, basis, 'HitTest', 'off')
            
            if ~isempty(app.knots)
                hold(app.KnotsUIAxes, 'on')
                xline(app.KnotsUIAxes, app.knots * range(app.smoother.data.t) + app.smoother.data.t(1), 'k--', 'HitTest', 'off')
                hold(app.KnotsUIAxes, 'off')
            end
            
            xlim(app.KnotsUIAxes, [min(app.smoother.data.t) max(app.smoother.data.t)])
            ylim(app.KnotsUIAxes, [0 1])
            
            if app.LiveCheckBox.Value
                app.plot_smoothed()
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, smoother)
            app.smoother = smoother;
            
            app.newlambda = false;
            
            N = smoother.data.N;
            nplots = min(12, length(smoother.data.observed));
            app.lastplots = gobjects(N, nplots);
            app.active = false(1, length(smoother.data.observed));
            app.active(1:nplots) = true;
            
            app.knots = smoother.settings.knots(~ismember(smoother.settings.knots, [0 1]));
            app.EquidistantknotsSlider.Value = length(app.knots);
            
%             app.EquidistantknotsSlider.Value = round((smoother.data.T-1)*.75);
%             app.knots = linspace(0, 1, app.EquidistantknotsSlider.Value+2);
%             app.knots = app.knots(2:end-1);
            disp(app.knots)
            
            state_names = convertStringsToChars(smoother.system.states(smoother.data.observed));
            if length(smoother.data.observed) == 1
                state_names = {state_names};
            end
            app.StatesListBox.Items = state_names;
            app.StatesListBox.Value = app.StatesListBox.Items(1:nplots);
            
            app.CellsSlider.Limits = [1 N];
            app.CellsSlider.Value = min(20, N);
            
            steps = [1 2 5 10 25 50 100 250 500 1000 2500 5000 10000];
            [~, minIdx] = min(abs(N/5 - steps));
            majorsize = steps(minIdx);
            minorsize = ceil(majorsize/5);
            app.CellsSlider.MajorTicksMode = 'manual';
            app.CellsSlider.MajorTicks = [1 majorsize : majorsize : N-mod(N,ceil(majorsize/2))-ceil(majorsize/2) N];
            app.CellsSlider.MinorTicksMode = 'manual';
            app.CellsSlider.MinorTicks = [1 minorsize : minorsize : N-minorsize];
            
            app.SmoothButton.Enable = false;
            
            app.setup_plots()
            app.update_knots()
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            app.smooth()
            app.plot_smoothed()
        end

        % Callback function
        function OrderSliderValueChanged(app, event)
            value = app.OrderSlider.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
%             app.OrderSlider.Value = event.Source.MinorTicks(minIdx);
            if app.LiveCheckBox.Value
                app.smooth();
                app.plot_smoothed()
            end
        end

        % Value changed function: EquidistantknotsSlider
        function EquidistantknotsSliderValueChanged(app, event)
            value = app.EquidistantknotsSlider.Value;
            event.Source.Value = round(value);
            
            app.knots = linspace(0, 1, app.EquidistantknotsSlider.Value+2);
            app.knots = app.knots(2:end-1);
            
            app.update_knots()
        end

        % Callback function
        function LinearSliderValueChanged(app, event)
            value = app.LinearSlider.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
%             app.OrderSlider.Value = event.Source.MinorTicks(minIdx);
            if app.LiveCheckBox.Value
                app.smooth()
                app.plot_smoothed()
            end
        end

        % Value changed function: correctionmultiplierSlider
        function correctionmultiplierSliderValueChanged(app, event)
            app.newlambda = true;
            value = app.correctionmultiplierSlider.Value;
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(minIdx);
%             app.OrderSlider.Value = event.Source.MinorTicks(minIdx);
            if app.LiveCheckBox.Value
                app.smooth()
                app.plot_smoothed()
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.FinishButton.UserData = 1;
        end

        % Value changed function: LiveCheckBox
        function LiveCheckBoxValueChanged(app, event)
            app.SmoothButton.Enable = ~app.LiveCheckBox.Value;
        end

        % Callback function
        function StatesListBoxValueChanged(app, event)
            app.active = ismember(app.StatesListBox.Items, app.StatesListBox.Value);
            app.setup_plots()
            app.plot_smoothed()
        end

        % Value changed function: CellsSlider
        function CellsSliderValueChanged(app, event)
            event.Source.Value = round(app.CellsSlider.Value);
            app.setup_plots()
            app.plot_smoothed()
        end

        % Button down function: KnotsUIAxes
        function KnotsUIAxesButtonDown(app, event)
            x = app.KnotsUIAxes.CurrentPoint(1,1);
            
            knots_rescaled = app.knots * range(app.smoother.data.t) + app.smoother.data.t(1);
            x_normalized = (x - app.smoother.data.t(1)) / range(app.smoother.data.t);
            
            if strcmp(app.UIFigure.SelectionType, 'alt')
                if length(app.knots) > 1
                    distances = abs(x - knots_rescaled);
                    app.knots = app.knots(distances > min(distances));
                else
                    app.knots = [];
                end
            else
                app.knots = [app.knots x_normalized];
            end
            
            app.knots = sort(app.knots);
            
            app.update_knots()
        end

        % Callback function
        function LambdaEditFieldValueChanged(app, event)
            app.newlambda = true;
            value = app.LambdaEditField.Value;
            app.correctionmultiplierSlider.Enable = false;
            app.state_lambda = max(0, str2double(strsplit(value,',')));
            if app.LiveCheckBox.Value
                app.smooth()
                app.plot_smoothed()
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {900, 900};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.KnotsUIAxes = uiaxes(app.LeftPanel);
            title(app.KnotsUIAxes, 'Knot selector')
            xlabel(app.KnotsUIAxes, 'time')
            app.KnotsUIAxes.ButtonDownFcn = createCallbackFcn(app, @KnotsUIAxesButtonDown, true);
            app.KnotsUIAxes_2 = uiaxes(app.LeftPanel);
            title(app.KnotsUIAxes_2, 'Penalized interval selector')
            xlabel(app.KnotsUIAxes_2, 'time')
            app.KnotsUIAxes_2.YTick = [];
            app.SmoothButton = uibutton(app.LeftPanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.LiveCheckBox = uicheckbox(app.LeftPanel);
            app.LiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveCheckBoxValueChanged, true);
            app.LiveCheckBox.Text = 'Live';
            app.LiveCheckBox.Value = true;
            app.EquidistantknotsSliderLabel = uilabel(app.LeftPanel);
            app.EquidistantknotsSliderLabel.Text = 'Equidistant knots';
            app.EquidistantknotsSlider = uislider(app.LeftPanel);
            app.EquidistantknotsSlider.Limits = [0 40];
            app.EquidistantknotsSlider.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.EquidistantknotsSlider.MajorTickLabels = {'0', ' ', '10', ' ', '20', ' ', '30', ' ', '40'};
            app.EquidistantknotsSlider.ValueChangedFcn = createCallbackFcn(app, @EquidistantknotsSliderValueChanged, true);
            app.EquidistantknotsSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.EquidistantknotsSlider.Value = 20;
            app.logLabel = uilabel(app.LeftPanel);
            app.logLabel.Text = 'λ correction multiplier';
            app.correctionmultiplierSlider = uislider(app.LeftPanel);
            app.correctionmultiplierSlider.Limits = [-4 4];
            app.correctionmultiplierSlider.MajorTicks = [-4 -2 0 2 4];
            app.correctionmultiplierSlider.MajorTickLabels = {'0.0001', '0.01', '1', '100', '10000'};
            app.correctionmultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @correctionmultiplierSliderValueChanged, true);
            app.correctionmultiplierSlider.MinorTicks = [-4 -3 -2 -1 0 1 2 3 4];
            app.FinishButton = uibutton(app.LeftPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.CellsLabel = uilabel(app.LeftPanel);
            app.CellsLabel.Text = '#Cells';
            app.CellsSlider = uislider(app.LeftPanel);
            app.CellsSlider.ValueChangedFcn = createCallbackFcn(app, @CellsSliderValueChanged, true);
            app.StateDropDownLabel = uilabel(app.LeftPanel);
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.LeftPanel);
            app.StateDropDown.Items = {'All'};
            app.StateDropDown.Value = 'All';
            app.AutomaticknotplacementButton = uibutton(app.LeftPanel, 'push');
            app.AutomaticknotplacementButton.Text = 'Automatic knot placement';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smoothing_new(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end